classdef CovidDataVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CountryListBoxLabel       matlab.ui.control.Label
        CountryListBox            matlab.ui.control.ListBox
        StateorRegionLabel        matlab.ui.control.Label
        StateorRegionListBox      matlab.ui.control.ListBox
        DatatoPlotButtonGroup     matlab.ui.container.ButtonGroup
        CasesButton               matlab.ui.control.RadioButton
        DeathButton               matlab.ui.control.RadioButton
        BothButton                matlab.ui.control.RadioButton
        OptionButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton          matlab.ui.control.RadioButton
        DailyButton               matlab.ui.control.RadioButton
        AverageofdaysSliderLabel  matlab.ui.control.Label
        AverageofdaysSlider       matlab.ui.control.Slider
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        World       % World
        Countries   % List of Countries
        CountryNames % List of Country Names
        CurrentCountry % Country to displayed
        CurrentOption  % Cumalative or Daily
        CurrentState   % State selected
        CurrentData    % Data to plot
        CurrentSliderValue
        Duration
    end
    
    properties (Access = private, Constant)
        OPTIONDAILY = 1;
        OPTIONCUMULATIVE = 2;
        DATACASES = 3;
        DATADEATHS = 4;
        DATABOTH = 5;
    end
    
    methods (Access = private)
        
        function SetTitle(app)
            OptionString = '';
            DataString = '';
            
            switch app.CurrentOption
                case app.OPTIONDAILY
                    OptionString = 'Daily';
                case app.OPTIONCUMULATIVE
                    OptionString = 'Cumulative';
            end
            switch app.CurrentData
                case app.DATACASES
                    DataString = 'Cases';
                case app.DATADEATHS
                    DataString = 'Deaths';
                case app.DATABOTH
                    DataString = 'Cases and Deaths';
            end
            RegionName = app.CurrentState.GetName();
            if (RegionName == 'All')
                RegionName = 'in ' + app.CurrentCountry.GetName();
            end
            if (app.CurrentCountry.GetName() == 'Global')
                RegionName = 'Globally';
            end
            
            MeanString = '';
            if app.CurrentSliderValue ~= 1
                MeanString = '(' + string(round(app.CurrentSliderValue)) + '-day mean)';
            end
            
            TitleString = sprintf('%s Number of %s %s %s', OptionString, DataString, RegionName, MeanString);
            set(app.UIAxes.Title,'String', TitleString);
        end
        
        % Plot the data depending on the state of the object variables.
        function PlotData(app)
            cla(app.UIAxes);
            reset(app.UIAxes);
            
            if app.CurrentOption == app.OPTIONDAILY
                cases = app.CurrentState.GetDailyCases();
                deaths = app.CurrentState.GetDailyDeaths();
            elseif app.CurrentOption == app.OPTIONCUMULATIVE
                cases = app.CurrentState.GetCumulativeCases();
                deaths = app.CurrentState.GetCumulativeDeaths();
            end
            cases = movmean(cases, [app.CurrentSliderValue, 0]);
            deaths = movmean(deaths, [app.CurrentSliderValue, 0]);
            
            grid(app.UIAxes, "on");
            
            if app.CurrentData == app.DATACASES
                bar(app.UIAxes, cases);
                ytickformat(app.UIAxes, '%d');
                app.UIAxes.YAxis.Exponent = 0;
            elseif app.CurrentData == app.DATADEATHS
                plot(app.UIAxes, deaths, 'r');
                ytickformat(app.UIAxes, '%d');
                app.UIAxes.YAxis.Exponent = 0;
            elseif app.CurrentData == app.DATABOTH
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, deaths, 'r');
                app.UIAxes.YAxis(2).Exponent = 0;
                ytickformat(app.UIAxes, '%d');
                
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, cases);
                app.UIAxes.YAxis(1).Exponent = 0;
                ytickformat(app.UIAxes, '%d');
            end
            
            SetTitle(app);
            set(app.UIAxes,'box','off')
            app.UIAxes.XTick = app.Duration;
            datetick(app.UIAxes, 'x', 6, 'keeplimits');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the members
            app.World = World();
            app.Countries = app.World.GetCountries();
            app.CurrentCountry = app.Countries(1);
            app.CountryNames = app.World.GetCountriesNames();
            app.CountryListBox.Items = app.CountryNames();
            app.StateorRegionListBox.Items = app.Countries(1).GetStatesNames();
            app.CurrentState = app.Countries(1).GetStates();
            app.CurrentState = app.CurrentState(1);
            app.CurrentData = app.DATACASES;
            app.CurrentOption = app.OPTIONCUMULATIVE;
            app.CurrentSliderValue = app.AverageofdaysSlider.Value;
            
            [Start, End] = app.World.GetDurationInterval();
            app.Duration = Start:caldays(1):End;
            app.Duration = datenum(app.Duration);
            
            app.PlotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            CountryName = app.CountryListBox.Value;
            Country = app.World.GetCountryByName(CountryName);
            app.StateorRegionListBox.Items = Country.GetStatesNames();
            app.CurrentCountry = Country;
            app.CurrentState = Country.GetStateByName('All');
            % Plot global data in graph
            app.PlotData();
        end

        % Value changed function: AverageofdaysSlider
        function CumulativeSliderValueChanged(app, event)
            app.CurrentSliderValue = app.AverageofdaysSlider.Value;
            app.PlotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            switch(selectedButton)
                case app.CasesButton
                    app.CurrentData = app.DATACASES;
                case app.DeathButton
                    app.CurrentData = app.DATADEATHS;
                case app.BothButton
                    app.CurrentData = app.DATABOTH;
            end
            app.PlotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            switch(selectedButton)
                case app.DailyButton
                    app.CurrentOption = app.OPTIONDAILY;
                case app.CumulativeButton
                    app.CurrentOption = app.OPTIONCUMULATIVE;
            end
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            StateName = app.StateorRegionListBox.Value;
            app.CurrentState = app.CurrentCountry.GetStateByName(StateName);
            app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AverageofdaysSliderLabel = uilabel(app.UIFigure);
            app.AverageofdaysSliderLabel.Text = {'Average'; '# of days'};
            app.AverageofdaysSlider = uislider(app.UIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @CumulativeSliderValueChanged, true);
            app.AverageofdaysSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, {''; ''})
            ylabel(app.UIAxes, {''; ''})
            zlabel(app.UIAxes, {''; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidDataVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end